classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PopulacjaLabel                  matlab.ui.control.Label
        PopulacjaEditField              matlab.ui.control.NumericEditField
        STARTButton                     matlab.ui.control.Button
        PEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        WartofunkcjiocenyEditFieldLabel  matlab.ui.control.Label
        WartofunkcjiocenyEditField      matlab.ui.control.NumericEditField
        ObliczonenastawyregulatoraLabel  matlab.ui.control.Label
        WzmocnienieKLabel               matlab.ui.control.Label
        WzmocnienieKEditField           matlab.ui.control.NumericEditField
        StaaczasowaT1Label              matlab.ui.control.Label
        StaaczasowaT1EditField          matlab.ui.control.NumericEditField
        StaaczasowaT2Label              matlab.ui.control.Label
        StaaczasowaT2EditField          matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        OpnienietauLabel                matlab.ui.control.Label
        OpnienietauEditField            matlab.ui.control.NumericEditField
        IlobitwLabel                    matlab.ui.control.Label
        IlobitwEditField                matlab.ui.control.NumericEditField
        PodajparametryobiektuLabel      matlab.ui.control.Label
        WybierzmetodselekcjiLabel       matlab.ui.control.Label
        MetodaselekcjiDropDownLabel     matlab.ui.control.Label
        MetodaselekcjiDropDown          matlab.ui.control.DropDown
        DostosujwarunkisymulacjiLabel   matlab.ui.control.Label
        PodajwarunkizatrzymaniaButtonGroup  matlab.ui.container.ButtonGroup
        IloiteracjiButton               matlab.ui.control.RadioButton
        DopkifunkcjaceluButton          matlab.ui.control.RadioButton
        IteracjeEditField               matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        CzassymulacjiEditFieldLabel     matlab.ui.control.Label
        CzassymulacjiEditField          matlab.ui.control.NumericEditField
        AktualnaiteracjaEditFieldLabel  matlab.ui.control.Label
        AktualnaiteracjaEditField       matlab.ui.control.NumericEditField
        PoszczeglneskadnikifunkcjiceluLabel  matlab.ui.control.Label
        PrzeregulowanieEditFieldLabel   matlab.ui.control.Label
        PrzeregulowanieEditField        matlab.ui.control.NumericEditField
        CzasregulacjiEditFieldLabel     matlab.ui.control.Label
        CzasregulacjiEditField          matlab.ui.control.NumericEditField
        UchybEditFieldLabel             matlab.ui.control.Label
        UchybEditField                  matlab.ui.control.NumericEditField
        BdEditFieldLabel                matlab.ui.control.Label
        BdEditField                     matlab.ui.control.NumericEditField
        PowdzatrzymaniaZakoczenieoperacjiLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        app.MetodaselekcjiDropDown.ItemsData = [1 2];
        
            % pierwszy warunek:
            
            if (app.IloiteracjiButton.Value == true && app.MetodaselekcjiDropDown.Value == 1)
        
        z = app.PopulacjaEditField.Value;
        z1 = app.IteracjeEditField.Value;
        zmienne(1) = app.WzmocnienieKEditField.Value;
        zmienne(2) = app.OpnienietauEditField.Value;
        zmienne(3) = app.StaaczasowaT1EditField.Value;
        zmienne(4) = app.StaaczasowaT2EditField.Value;
        ile_bitow = app.IlobitwEditField.Value;
        czas_symulacji = app.CzassymulacjiEditField.Value;
        
        n=population_draw(z, ile_bitow);
        f_1=bin_dec_conv(n);
        % config_1 = f_1;

            for i=1:z1
    
            draw_r = roulette_draw(f_1, zmienne, czas_symulacji);
            f_1_conv = dec_bin_conv(draw_r);
            cruc = crucifixion(f_1_conv);
    
            mut = mutation(cruc);
            f_1=bin_dec_conv(mut);
            
            app.AktualnaiteracjaEditField.Value = i;
            
            y(i) = funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
            plot(app.UIAxes,(y));
            
            pause(0);
            end 
    
    final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
    app.PEditField.Value = f_1(1,1);
    app.IEditField.Value = f_1(1,2);
    app.DEditField.Value = f_1(1,3);
    
    funwysw = funkcja_oceny_wyswietlanie(f_1(1,:), zmienne, czas_symulacji);
    app.PrzeregulowanieEditField.Value = funwysw(1,1);
    app.CzasregulacjiEditField.Value = funwysw(1,2);
    app.UchybEditField.Value = funwysw(1,3);
    app.BdEditField.Value = funwysw(1,4);
    
    app.PowdzatrzymaniaZakoczenieoperacjiLabel.Visible = 'on';
    
    app.WartofunkcjiocenyEditField.Value = final;
    
            end
            
            % drugi warunek:
            
            if (app.IloiteracjiButton.Value == true && app.MetodaselekcjiDropDown.Value == 2)
                
        ile_bitow = app.IlobitwEditField.Value;
        z = app.PopulacjaEditField.Value;
        z1 = app.IteracjeEditField.Value;
        zmienne(1) = app.WzmocnienieKEditField.Value;
        zmienne(2) = app.OpnienietauEditField.Value;
        zmienne(3) = app.StaaczasowaT1EditField.Value;
        zmienne(4) = app.StaaczasowaT2EditField.Value;
        czas_symulacji = app.CzassymulacjiEditField.Value;
        
        n=population_draw(z, ile_bitow);
        f_1=bin_dec_conv(n);
        % config_1 = f_1;

            for i=1:z1
    
            draw_r = threshold(f_1, zmienne, czas_symulacji);
            f_1_conv = dec_bin_conv(draw_r);
            cruc = crucifixion_t(f_1_conv);
    
            mut = mutation(cruc);
            f_1=bin_dec_conv(mut);
            
            app.AktualnaiteracjaEditField.Value = i;
            y(i) = funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
            plot(app.UIAxes,(y));

            pause(0);
            end 
    
    final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
    app.PEditField.Value = f_1(1,1);
    app.IEditField.Value = f_1(1,2);
    app.DEditField.Value = f_1(1,3);
    
    funwysw = funkcja_oceny_wyswietlanie(f_1(1,:), zmienne, czas_symulacji);
    app.PrzeregulowanieEditField.Value = funwysw(1,1);
    app.CzasregulacjiEditField.Value = funwysw(1,2);
    app.UchybEditField.Value = funwysw(1,3);
    app.BdEditField.Value = funwysw(1,4);
    
    app.PowdzatrzymaniaZakoczenieoperacjiLabel.Visible = 'on';
    
    app.WartofunkcjiocenyEditField.Value = final;
    
            end
            
            % trzeci warunek:
            
            if (app.DopkifunkcjaceluButton.Value == true && app.MetodaselekcjiDropDown.Value == 1)
                
        z = app.PopulacjaEditField.Value;
        z2 = app.EditField_2.Value;
        zmienne(1) = app.WzmocnienieKEditField.Value;
        zmienne(2) = app.OpnienietauEditField.Value;
        zmienne(3) = app.StaaczasowaT1EditField.Value;
        zmienne(4) = app.StaaczasowaT2EditField.Value;
        ile_bitow = app.IlobitwEditField.Value;
        czas_symulacji = app.CzassymulacjiEditField.Value;
        
        n=population_draw(z, ile_bitow);
        f_1=bin_dec_conv(n);
        % config_1 = f_1;
        final = z2;
        i = 0;
            while final > z2-1
            i = i+1;
            draw_r = roulette_draw(f_1, zmienne, czas_symulacji);
            f_1_conv = dec_bin_conv(draw_r,ile_bitow);
            cruc = crucifixion(f_1_conv);
    
            mut = mutation(cruc);
            f_1=bin_dec_conv(mut);
            
            app.AktualnaiteracjaEditField.Value = i;
            y(i) = funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
            plot(app.UIAxes,(y));
            
            final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
            pause(0);
            
            end 
    
    final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
    app.PEditField.Value = f_1(1,1);
    app.IEditField.Value = f_1(1,2);
    app.DEditField.Value = f_1(1,3);
    
    funwysw = funkcja_oceny_wyswietlanie(f_1(1,:), zmienne, czas_symulacji);
    app.PrzeregulowanieEditField.Value = funwysw(1,1);
    app.CzasregulacjiEditField.Value = funwysw(1,2);
    app.UchybEditField.Value = funwysw(1,3);
    app.BdEditField.Value = funwysw(1,4);
    
    app.PowdzatrzymaniaZakoczenieoperacjiLabel.Visible = 'on';
    
    app.WartofunkcjiocenyEditField.Value = final;
                
            end
            
                        % czwarty warunek:
            
            if (app.DopkifunkcjaceluButton.Value == true && app.MetodaselekcjiDropDown.Value == 2)
                
        z = app.PopulacjaEditField.Value;
        z2 = app.EditField_2.Value;
        zmienne(1) = app.WzmocnienieKEditField.Value;
        zmienne(2) = app.OpnienietauEditField.Value;
        zmienne(3) = app.StaaczasowaT1EditField.Value;
        zmienne(4) = app.StaaczasowaT2EditField.Value;
        ile_bitow = app.IlobitwEditField.Value;
        czas_symulacji = app.CzassymulacjiEditField.Value;
        
        n=population_draw(z, ile_bitow);
        f_1=bin_dec_conv(n);
        % config_1 = f_1;
        final = z2;
        i = 0;

            while final > z2-1
            i = i+1;
            draw_r = threshold(f_1, zmienne, czas_symulacji);
            f_1_conv = dec_bin_conv(draw_r,ile_bitow);
            cruc = crucifixion_t(f_1_conv);
    
            mut = mutation(cruc);
            f_1=bin_dec_conv(mut);
            
            app.AktualnaiteracjaEditField.Value = i;
            y(i) = funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
            plot(app.UIAxes,(y));
            
            final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);

            end 
    
    final=funkcja_oceny(f_1(1,:), zmienne, czas_symulacji);
    app.PEditField.Value = f_1(1,1);
    app.IEditField.Value = f_1(1,2);
    app.DEditField.Value = f_1(1,3);
    
    funwysw = funkcja_oceny_wyswietlanie(f_1(1,:), zmienne, czas_symulacji);
    app.PrzeregulowanieEditField.Value = funwysw(1,1);
    app.CzasregulacjiEditField.Value = funwysw(1,2);
    app.UchybEditField.Value = funwysw(1,3);
    app.BdEditField.Value = funwysw(1,4);
    
    app.PowdzatrzymaniaZakoczenieoperacjiLabel.Visible = 'on';
    
    app.WartofunkcjiocenyEditField.Value = final;
                
            end
           
        end

        % Value changed function: PopulacjaEditField
        function PopulacjaEditFieldValueChanged(app, event)
            % value = app.PopulacjaEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PopulacjaLabel = uilabel(app.UIFigure);
            app.PopulacjaLabel.Text = 'Populacja:';
            app.PopulacjaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulacjaEditField.ValueChangedFcn = createCallbackFcn(app, @PopulacjaEditFieldValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Editable = 'off';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Editable = 'off';
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.Editable = 'off';
            app.WartofunkcjiocenyEditFieldLabel = uilabel(app.UIFigure);
            app.WartofunkcjiocenyEditFieldLabel.Text = 'Wartość funkcji oceny:';
            app.WartofunkcjiocenyEditField = uieditfield(app.UIFigure, 'numeric');
            app.WartofunkcjiocenyEditField.Editable = 'off';
            app.ObliczonenastawyregulatoraLabel = uilabel(app.UIFigure);
            app.ObliczonenastawyregulatoraLabel.Text = 'Obliczone nastawy regulatora:';
            app.WzmocnienieKLabel = uilabel(app.UIFigure);
            app.WzmocnienieKLabel.Text = 'Wzmocnienie (K):';
            app.WzmocnienieKEditField = uieditfield(app.UIFigure, 'numeric');
            app.StaaczasowaT1Label = uilabel(app.UIFigure);
            app.StaaczasowaT1Label.Text = 'Stała czasowa (T1):';
            app.StaaczasowaT1EditField = uieditfield(app.UIFigure, 'numeric');
            app.StaaczasowaT2Label = uilabel(app.UIFigure);
            app.StaaczasowaT2Label.Text = 'Stała czasowa (T2):';
            app.StaaczasowaT2EditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Funkcja celu najlepszego osobnika w kolejnych iteracjach')
            xlabel(app.UIAxes, 'Iteracje')
            ylabel(app.UIAxes, 'Wartość')
            app.OpnienietauLabel = uilabel(app.UIFigure);
            app.OpnienietauLabel.Text = 'Opóźnienie (tau):';
            app.OpnienietauEditField = uieditfield(app.UIFigure, 'numeric');
            app.IlobitwLabel = uilabel(app.UIFigure);
            app.IlobitwLabel.Text = 'Ilość bitów:';
            app.IlobitwEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajparametryobiektuLabel = uilabel(app.UIFigure);
            app.PodajparametryobiektuLabel.Text = 'Podaj parametry obiektu:';
            app.WybierzmetodselekcjiLabel = uilabel(app.UIFigure);
            app.WybierzmetodselekcjiLabel.Text = 'Wybierz metodę selekcji:';
            app.MetodaselekcjiDropDownLabel = uilabel(app.UIFigure);
            app.MetodaselekcjiDropDownLabel.Text = 'Metoda selekcji:';
            app.MetodaselekcjiDropDown = uidropdown(app.UIFigure);
            app.MetodaselekcjiDropDown.Items = {'Koło ruletki', 'Metoda rangowa', ''};
            app.MetodaselekcjiDropDown.Value = 'Koło ruletki';
            app.DostosujwarunkisymulacjiLabel = uilabel(app.UIFigure);
            app.DostosujwarunkisymulacjiLabel.Text = 'Dostosuj warunki symulacji:';
            app.PodajwarunkizatrzymaniaButtonGroup = uibuttongroup(app.UIFigure);
            app.PodajwarunkizatrzymaniaButtonGroup.Title = 'Podaj warunki zatrzymania:';
            app.IloiteracjiButton = uiradiobutton(app.PodajwarunkizatrzymaniaButtonGroup);
            app.IloiteracjiButton.Text = 'Ilość iteracji:';
            app.IloiteracjiButton.Value = true;
            app.DopkifunkcjaceluButton = uiradiobutton(app.PodajwarunkizatrzymaniaButtonGroup);
            app.DopkifunkcjaceluButton.Text = 'Dopóki funkcja celu <= :';
            app.IteracjeEditField = uieditfield(app.PodajwarunkizatrzymaniaButtonGroup, 'numeric');
            app.EditField_2 = uieditfield(app.PodajwarunkizatrzymaniaButtonGroup, 'numeric');
            app.CzassymulacjiEditFieldLabel = uilabel(app.UIFigure);
            app.CzassymulacjiEditFieldLabel.Text = 'Czas symulacji:';
            app.CzassymulacjiEditField = uieditfield(app.UIFigure, 'numeric');
            app.AktualnaiteracjaEditFieldLabel = uilabel(app.UIFigure);
            app.AktualnaiteracjaEditFieldLabel.Text = 'Aktualna iteracja:';
            app.AktualnaiteracjaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoszczeglneskadnikifunkcjiceluLabel = uilabel(app.UIFigure);
            app.PoszczeglneskadnikifunkcjiceluLabel.Text = 'Poszczególne składniki funkcji celu:';
            app.PrzeregulowanieEditFieldLabel = uilabel(app.UIFigure);
            app.PrzeregulowanieEditFieldLabel.Text = 'Przeregulowanie:';
            app.PrzeregulowanieEditField = uieditfield(app.UIFigure, 'numeric');
            app.CzasregulacjiEditFieldLabel = uilabel(app.UIFigure);
            app.CzasregulacjiEditFieldLabel.Text = 'Czas regulacji:';
            app.CzasregulacjiEditField = uieditfield(app.UIFigure, 'numeric');
            app.UchybEditFieldLabel = uilabel(app.UIFigure);
            app.UchybEditFieldLabel.Text = 'Uchyb:';
            app.UchybEditField = uieditfield(app.UIFigure, 'numeric');
            app.BdEditFieldLabel = uilabel(app.UIFigure);
            app.BdEditFieldLabel.Text = 'Błąd:';
            app.BdEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowdzatrzymaniaZakoczenieoperacjiLabel = uilabel(app.UIFigure);
            app.PowdzatrzymaniaZakoczenieoperacjiLabel.Visible = 'off';
            app.PowdzatrzymaniaZakoczenieoperacjiLabel.Text = {'Powód zatrzymania:'; 'Zakończenie operacji.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end